classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ImportDataButton               matlab.ui.control.Button
        RefreshpointsButton            matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Spinner                        matlab.ui.control.Spinner
        SpinnerLabel                   matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        Prompt                         matlab.ui.control.TextArea
        PromptTextAreaLabel            matlab.ui.control.Label
        Show                           matlab.ui.container.ButtonGroup
        OnlypointsButton               matlab.ui.control.RadioButton
        OnlyaproximationcurveButton    matlab.ui.control.RadioButton
        AproximationcurvePointsButton  matlab.ui.control.RadioButton
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Spinner
        function SpinnerValueChanged(app, event)
            point_amount = app.Spinner.Value;           % Current spinner value
            previous_point_amount = event.PreviousValue;% Spinner value before change
            set(app.UITable, 'RowName', 'numbered');    
            if (point_amount > previous_point_amount)   % Increment
                default_values = {0, 0};
                old_data = get(app.UITable, 'data');    % Save table
                new_data = [old_data;default_values];   % New coordinates = previous coordinates & default coordinates
                set(app.UITable, 'data', new_data);     % Add new coordinates to table
            elseif (point_amount < previous_point_amount)                                        % Decrement -> delete last row
                app.UITable.Data((previous_point_amount), :) = [];
            else
                set(app.Prompt, 'Value', 'ERROR: Unexpected state of points amount spinner.');
            end
        end

        % Button pushed function: RefreshpointsButton
        function RefreshpointsButtonPushed(app, event)
            %             Reset prompt
            set(app.Prompt, 'Value', 'Idle ...')
            
            %             Check piont amount spinner
            if (get(app.Spinner, 'Value') == 0)
                set(app.Prompt, 'Value', 'ERROR: Point amount is set to zero, no data to display.');
                return;
            elseif (get(app.Spinner, 'Value') <= 0)
                set(app.Promot, 'Value', 'ERROR: Point amount cannot be negative number!');
                return;
            end
            
%             Convert table to matrix
            t = cell2mat(app.UITable.Data);
%             polynomial_aproximation_value = get(app.Spinner, 'Value') - 1;
            polynomial_aproximation_coefficients = polyfit(t(:,1), t(:,2), 2);

%            function = points, function1 = approximation curve
            x = t(:,1);
            y = t(:,2);
            x1 = min(t(:,1)) - 1 : 0.1 : max(t(:,1)) + 1;
            y1 = polyval(polynomial_aproximation_coefficients, x1);
            
%             Get show option
            if (get(app.OnlypointsButton, 'Value') == true)
                show_option = 'P';
            elseif (get(app.OnlyaproximationcurveButton, 'Value') == true)
                show_option = 'C';
            elseif (get(app.AproximationcurvePointsButton, 'Value') == true)
                show_option = 'CP';
            else
                set(app.Prompt, 'Value', 'ERROR: Cannot get value from show options.');
                return;
            end
            
%             Plot data
            switch show_option
                case 'CP'
                    plot(app.UIAxes, x1, y1, x, y, 'o');
                case 'C'
                    plot(app.UIAxes, x1, y1);
                case 'P'
                    plot(app.UIAxes, x, y, 'o');
            end
            
        end

        % Selection changed function: Show
        function ShowSelectionChanged(app, event)
            RefreshpointsButtonPushed(app, event);
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [file_name, file_path] = uigetfile({'*.xlsx'}, 'Select a file', 'C:\Documents\');
            if (isequal(file_name, 0))
                set(app.Prompt, 'Value', 'INFO: User did not selected no file, no data imported.');
                return;
            elseif (isequal(file_path, 0))
                set(app.Prompt, 'Value', 'ERROR: Failed getting file path, no data imported.');
            end
            
            file = fullfile(file_path, file_name);
            file_data = readtable(file);
            
            vars = {'X'; 'Y'};
            file_data = file_data(2:10, vars);
            set(app.UITable, 'Data', file_data);
%             table = cell2mat(app.UITable.Data);
%             set(app.Spinner, 'Value', get(table, max(table(:,2))));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SpinnerLabel = uilabel(app.LeftPanel);
            app.SpinnerLabel.Text = 'Počet bodů';
            app.Spinner = uispinner(app.LeftPanel);
            app.Spinner.Limits = [0 Inf];
            app.Spinner.ValueChangedFcn = createCallbackFcn(app, @SpinnerValueChanged, true);
            app.Spinner.Editable = 'off';
            app.UITable = uitable(app.LeftPanel);
            app.UITable.ColumnName = {'X'; 'Y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.RefreshpointsButton = uibutton(app.LeftPanel, 'push');
            app.RefreshpointsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshpointsButtonPushed, true);
            app.RefreshpointsButton.Text = 'Refresh points';
            app.ImportDataButton = uibutton(app.LeftPanel, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Show = uibuttongroup(app.RightPanel);
            app.Show.SelectionChangedFcn = createCallbackFcn(app, @ShowSelectionChanged, true);
            app.Show.Title = 'Show';
            app.AproximationcurvePointsButton = uiradiobutton(app.Show);
            app.AproximationcurvePointsButton.Text = 'Aproximation curve + Points';
            app.AproximationcurvePointsButton.Value = true;
            app.OnlyaproximationcurveButton = uiradiobutton(app.Show);
            app.OnlyaproximationcurveButton.Text = 'Only aproximation curve';
            app.OnlypointsButton = uiradiobutton(app.Show);
            app.OnlypointsButton.Text = 'Only points';
            app.PromptTextAreaLabel = uilabel(app.RightPanel);
            app.PromptTextAreaLabel.Text = 'Prompt';
            app.Prompt = uitextarea(app.RightPanel);
            app.Prompt.Editable = 'off';
            app.Prompt.Value = {'Idle ...'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end